classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        KonvolusiTab                matlab.ui.container.Tab
        TextArea                    matlab.ui.control.TextArea
        CalculateButton             matlab.ui.control.Button
        OutputLabel                 matlab.ui.control.Label
        InputImageButton            matlab.ui.control.Button
        XLabel                      matlab.ui.control.Label
        EditField_2                 matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        MASKLabel                   matlab.ui.control.Label
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        FilterTab                   matlab.ui.container.Tab
        Label_2                     matlab.ui.control.Label
        nLabel                      matlab.ui.control.Label
        EditField_4                 matlab.ui.control.NumericEditField
        EditField_3                 matlab.ui.control.NumericEditField
        CalculateButton_2           matlab.ui.control.Button
        OutputLabel_2               matlab.ui.control.Label
        InputImageButton_2          matlab.ui.control.Button
        DropDown                    matlab.ui.control.DropDown
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        BrighteningTab              matlab.ui.container.Tab
        EditField_5                 matlab.ui.control.NumericEditField
        BrightLabel                 matlab.ui.control.Label
        CalculateButton_3           matlab.ui.control.Button
        OutputLabel_3               matlab.ui.control.Label
        InputImageButton_3          matlab.ui.control.Button
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        Tugas2PemrosesanCitraLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageInput1 = nan
        ImageInput2 = nan
        ImageInput3 = nan
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes)
            disableDefaultInteractivity(app.UIAxes_2)
            disableDefaultInteractivity(app.UIAxes_3)
            disableDefaultInteractivity(app.UIAxes_4)
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput1 = imread(fullpath);
                imshow(image,'Parent',app.UIAxes_3);
            end
        end

        % Button pushed function: InputImageButton_2
        function InputImageButton_2Pushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput2 = imread(fullpath);
                imshow(image,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            pembilang = app.EditField.Value;
            penyebut = app.EditField_2.Value;
            if(penyebut == 0)
                warndlg("Deteminator value should not be 0","Input")
            end
            pengali = pembilang/penyebut;
            inputmask = app.TextArea.Value;
            inputmask = inputmask(inputmask ~= "");
            row = length(inputmask);
            checkmatrix = false;
            col = 0;
            for i = 1: row
                arrnow = strsplit(inputmask{i},' ');
                arrnow = arrnow(arrnow ~= "");
                colnow = length(arrnow);
                if(colnow ~= row)
                    checkmatrix = false;
                    break
                else
                    checkmatrix = true;
                    col = colnow;
                end
            end
            if(checkmatrix == false)
                warndlg("Input Matrix Not True or Not Square", "Input")
            else
                if(mod(row,2) ~= 0)
                    checknum = true;
                    maskmatrix = zeros(row,colnow);
                    for i = 1: row
                        arrnow = strsplit(inputmask{i},' ');
                        arrnow = arrnow(arrnow ~= "");
                        colnow = length(arrnow);
                        for j = 1:colnow
                            checknumnow= str2double(arrnow{j});
                            if(isnan(checknumnow))
                                checknum = false;
                                break
                            else
                                maskmatrix(i,j) = checknumnow;
                            end
                        end
                    end
                    if(checknum == false)
                        warndlg("Input Matrix Must Number", "Input")
                    else
                        maskmatrixfinal = pengali * maskmatrix;
                        if(isnan(app.ImageInput1))
                            warndlg("Must Input Image First", "Input")
                        else
                            hasil = konvolusi(im2double(app.ImageInput1),double(maskmatrixfinal));
                            hasil = im2uint8(hasil);
                            imshow(hasil,'Parent',app.UIAxes_4);
                        end
                    end
                else
                    warndlg("Row And Column Must Odd", "Input")
                end
            end


        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            if(isnan(app.ImageInput2))
                warndlg("Must Input Image First", "Input")
            else
                if(app.DropDown.Value == "Mean Filter")
                    if(mod(app.EditField_3.Value,2)==0)
                        warndlg("Input N Must Odd","Input")
                    else
                        hasil = meanfilter(app.ImageInput2,app.EditField_3.Value);
                        imshow(hasil,'Parent',app.UIAxes_2)
                    end
                elseif(app.DropDown.Value == "Gaussian Filter")
                    if(mod(app.EditField_3.Value,2)==0)
                        warndlg("Input N Must Odd","Input")
                    else
                        hasil = gaussfilter(app.ImageInput2,app.EditField_3.Value,app.EditField_4.Value);
                        imshow(hasil,'Parent',app.UIAxes_2)
                    end
                elseif(app.DropDown.Value == "Ideal Low Pass Filter")
 
                    hasil = ilpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Gaussian Low Pass Filter")
 
                    hasil = glpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Butterworth Low Pass Filter")
 
                    hasil = blpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Ideal High Pass Filter")
 
                    hasil = ihpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Gaussian High Pass Filter")
 
                    hasil = ghpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Butterworth High Pass Filter")
 
                    hasil = bhpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)

                end
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if(value == "Mean Filter")
                app.EditField_3.Visible = "on";
                app.EditField_4.Visible = "off";
                app.nLabel.Visible = "on";
                app.nLabel.Text = "n";
                app.Label_2.Visible = "off";
            elseif(value == "Gaussian Filter")
                app.EditField_3.Visible = "on";
                app.EditField_4.Visible = "on";
                app.nLabel.Visible = "on";
                app.Label_2.Visible = "on";
                app.nLabel.Text = "n";
                app.Label_2.Text = "σ";
            else
                app.EditField_3.Visible = "on";
                app.EditField_4.Visible = "off";
                app.nLabel.Visible = "on";
                app.nLabel.Text = "D";
                app.Label_2.Visible = "off";
            end
        end

        % Button pushed function: InputImageButton_3
        function InputImageButton_3Pushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput3 = imread(fullpath);
                imshow(image,'Parent',app.UIAxes_5);
            end
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            if(isnan(app.ImageInput3))
                warndlg("Must Input Image First", "Input")
            else
                hasil = frequency_brightening(app.ImageInput3,app.EditField_5.Value);
                imshow(hasil,'Parent',app.UIAxes_6)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tugas2PemrosesanCitraLabel = uilabel(app.UIFigure);
            app.Tugas2PemrosesanCitraLabel.Text = 'Tugas 2 Pemrosesan Citra';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KonvolusiTab = uitab(app.TabGroup);
            app.KonvolusiTab.Title = 'Konvolusi';
            app.UIAxes_3 = uiaxes(app.KonvolusiTab);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.KonvolusiTab);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.MASKLabel = uilabel(app.KonvolusiTab);
            app.MASKLabel.Text = 'MASK = ';
            app.EditField = uieditfield(app.KonvolusiTab, 'numeric');
            app.EditField.Value = 1;
            app.Label = uilabel(app.KonvolusiTab);
            app.Label.Text = '___';
            app.EditField_2 = uieditfield(app.KonvolusiTab, 'numeric');
            app.EditField_2.Value = 1;
            app.XLabel = uilabel(app.KonvolusiTab);
            app.XLabel.Text = 'X';
            app.InputImageButton = uibutton(app.KonvolusiTab, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.OutputLabel = uilabel(app.KonvolusiTab);
            app.OutputLabel.Text = 'Output';
            app.CalculateButton = uibutton(app.KonvolusiTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TextArea = uitextarea(app.KonvolusiTab);
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.UIAxes = uiaxes(app.FilterTab);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.FilterTab);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.DropDown = uidropdown(app.FilterTab);
            app.DropDown.Items = {'Mean Filter', 'Gaussian Filter', 'Ideal Low Pass Filter', 'Gaussian Low Pass Filter', 'Butterworth Low Pass Filter', 'Ideal High Pass Filter', 'Gaussian High Pass Filter', 'Butterworth High Pass Filter'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Mean Filter';
            app.InputImageButton_2 = uibutton(app.FilterTab, 'push');
            app.InputImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputImageButton_2Pushed, true);
            app.InputImageButton_2.Text = 'Input Image';
            app.OutputLabel_2 = uilabel(app.FilterTab);
            app.OutputLabel_2.Text = 'Output';
            app.CalculateButton_2 = uibutton(app.FilterTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.EditField_3 = uieditfield(app.FilterTab, 'numeric');
            app.EditField_4 = uieditfield(app.FilterTab, 'numeric');
            app.EditField_4.Visible = 'off';
            app.nLabel = uilabel(app.FilterTab);
            app.nLabel.Text = 'n';
            app.Label_2 = uilabel(app.FilterTab);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = 'σ';
            app.BrighteningTab = uitab(app.TabGroup);
            app.BrighteningTab.Title = 'Brightening';
            app.UIAxes_5 = uiaxes(app.BrighteningTab);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.BrighteningTab);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.InputImageButton_3 = uibutton(app.BrighteningTab, 'push');
            app.InputImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @InputImageButton_3Pushed, true);
            app.InputImageButton_3.Text = 'Input Image';
            app.OutputLabel_3 = uilabel(app.BrighteningTab);
            app.OutputLabel_3.Text = 'Output';
            app.CalculateButton_3 = uibutton(app.BrighteningTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.BrightLabel = uilabel(app.BrighteningTab);
            app.BrightLabel.Text = 'Bright';
            app.EditField_5 = uieditfield(app.BrighteningTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end